classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PredictionResultLabel  matlab.ui.control.Label
        ResultLabel            matlab.ui.control.Label
        ModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel      matlab.ui.control.Label
        PredictButton          matlab.ui.control.Button
        FilenamelabelLabel     matlab.ui.control.Label
        ChooseImageButton      matlab.ui.control.Button
        PredictImage           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentCategoryClassifier
        currentNet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the model from files.
            load("./Model/bagOfFeatures.mat", "categoryClassifier");
            load("./Model/alexnet.mat", "net");

            % Set the property.
            app.currentCategoryClassifier = categoryClassifier;
            app.currentNet = net;
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
           [filename, pathname] = uigetfile('*.*', 'pick an image');

           % If the user cancels, return.
            if ~ischar(filename)
                return;
            end

           % Display filename
           app.FilenamelabelLabel.Text = filename;

           % Construct the full file path.
           filepath = fullfile(pathname, filename);

           % Read the image.
           img = imread(filepath);

           % Show the image.
           imshow(img, 'Parent', app.PredictImage);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % Get parameter.
            value = app.ModeDropDown.Value;

            % Get image.
            imageData = getimage(app.PredictImage);

            switch value
                case "Machine Learning"
                    result = classify_alexnet(app.currentNet, imageData);
                    app.PredictionResultLabel.Text = result;
                case "Non Machine Learning"
                    result = classify_bag_of_features(app.currentCategoryClassifier, imageData);
                    app.PredictionResultLabel.Text = result;
                otherwise
                    uialert(app.UIFigure, "Mode not recognized.", "Invalid Mode");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictImage = uiaxes(app.UIFigure);
            title(app.PredictImage, 'Image')
            app.PredictImage.XTick = [];
            app.PredictImage.YTick = [];
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.FilenamelabelLabel = uilabel(app.UIFigure);
            app.FilenamelabelLabel.Text = 'Filename label';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Non Machine Learning', 'Machine Learning'};
            app.ModeDropDown.Value = 'Non Machine Learning';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result: ';
            app.PredictionResultLabel = uilabel(app.UIFigure);
            app.PredictionResultLabel.Text = 'Prediction Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end